classdef AudioStegnography < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioStegnographyUIFigure       matlab.ui.Figure
        HideTextPanel                   matlab.ui.container.Panel
        SelectOriginalWaveFileButton    matlab.ui.control.Button
        SelectOriginalTextFileButton    matlab.ui.control.Button
        HideButton                      matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        EncryptionKeyEditFieldLabel     matlab.ui.control.Label
        EncryptionKeyEditField          matlab.ui.control.EditField
        RecoverTextPanel                matlab.ui.container.Panel
        SelectEmbeddedWaveFileButton_2  matlab.ui.control.Button
        RecoverButton                   matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        DecryptionKeyEditFieldLabel     matlab.ui.control.Label
        DecryptionKeyEditField          matlab.ui.control.EditField
        AudioSteganographyLabel         matlab.ui.control.Label
        PlayOriginalButton              matlab.ui.control.Button
        PlayEncodedButton               matlab.ui.control.Button
        PlotOriginalButton              matlab.ui.control.Button
        PlotEncodedButton               matlab.ui.control.Button
    end


    properties (Access = public)
       filename_org =' ';
       pathname_org = ' ';
       filename_encode = ' ';
       pathname_encode = ' ';
       filename_text_org = ' ';
       pathname_text_org = ' ';
       filename_text_recover = ' ';
       pathname_text_recover = ' ';
       hide_encrypt_key = ' ';
       hide_decrypt_key = ' ';
       x;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all
            app.Lamp.Color = 'red';
            app.Lamp_2.Color = 'red';
        end

        % Button pushed function: SelectOriginalWaveFileButton
        function SelectOriginalWaveFileButtonPushed(app, event)
            app.Lamp.Color = 'red';
            [app.filename_org, app.pathname_org] = uigetfile('*.wav','Select the original audio file'); 
            app.pathname_encode = app.pathname_org;
            app.filename_encode = strcat(erase(app.filename_org,'.wav'),'_Embeded.wav');
            if strcmp('C:\Program Files\MATLAB\Embed.wav',app.pathname_encode) 
            else
                copyfile ('C:\Program Files\MATLAB\Embed.wav', strcat(app.pathname_encode,app.filename_encode), 'f')
            end
            app.AudioStegnographyUIFigure.Visible = 'off';
            app.AudioStegnographyUIFigure.Visible = 'on';
        end

        % Button pushed function: SelectOriginalTextFileButton
        function SelectOriginalTextFileButtonPushed(app, event)
            [app.filename_text_org, app.pathname_text_org] = uigetfile('*.txt','Select the original text file'); 
            app.AudioStegnographyUIFigure.Visible = 'off';
            app.AudioStegnographyUIFigure.Visible = 'on';
        end

        % Button pushed function: HideButton
        function HideButtonPushed(app, event)
        app.x = textEmbedding(app.hide_encrypt_key, app.filename_org, app.pathname_org, app.filename_encode, app.pathname_encode, app.filename_text_org, app.pathname_text_org);
        if(app.x==1)
            app.Lamp.Color = 'green';
        else
            app.Lamp.Color = 'red';
        end
        end

        % Button pushed function: SelectEmbeddedWaveFileButton_2
        function SelectEmbeddedWaveFileButton_2Pushed(app, event)
            app.Lamp_2.Color = 'red';
            [app.filename_encode,app.pathname_encode] = uigetfile('*.wav','Select the to be modified audio file'); 
            app.pathname_text_recover = app.pathname_encode;
            app.filename_text_recover = strcat(erase(app.filename_encode,'.wav'),'_Recover.txt');
            if strcmp('C:\Program Files\MATLAB\Recover.txt',app.pathname_text_recover) 
            else
                copyfile ('C:\Program Files\MATLAB\Recover.txt', strcat(app.pathname_text_recover,app.filename_text_recover), 'f')
            end
            app.AudioStegnographyUIFigure.Visible = 'off';
            app.AudioStegnographyUIFigure.Visible = 'on';
        end

        % Button pushed function: RecoverButton
        function RecoverButtonPushed(app, event)
         app.x = textRecover(app.hide_decrypt_key, app.filename_encode, app.pathname_encode, app.filename_text_recover, app.pathname_text_recover);
         if(app.x==1)
                 app.Lamp_2.Color = 'green';
         else
             app.Lamp_2.Color = 'red';
         end
        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
            [y,Fs]=audioread([app.pathname_encode app.filename_encode]);
            sound(y)
        end

        % Button pushed function: PlayEncodedButton
        function PlayEncodedButtonPushed(app, event)
             [y,Fs]=audioread([app.pathname_org app.filename_org]);
            sound(y)
        end

        % Button pushed function: PlotOriginalButton
        function PlotOriginalButtonPushed(app, event)
            plotter(app.filename_org, app.pathname_org, 1)
        end

        % Button pushed function: PlotEncodedButton
        function PlotEncodedButtonPushed(app, event)
            plotter(app.filename_encode, app.pathname_encode, 2)
        end

        % Value changing function: EncryptionKeyEditField
        function EncryptionKeyEditFieldValueChanging(app, event)
            changingValueEncrypt = event.Value;
            app.hide_encrypt_key = changingValueEncrypt;
        end

        % Value changing function: DecryptionKeyEditField
        function DecryptionKeyEditFieldValueChanging(app, event)
            changingValueDecrypt = event.Value;
            app.hide_decrypt_key = changingValueDecrypt;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioStegnographyUIFigure = uifigure;
            app.AudioStegnographyUIFigure.Name = 'Audio Stegaography';
            app.HideTextPanel = uipanel(app.AudioStegnographyUIFigure);
            app.HideTextPanel.Title = 'Hide Text';
            app.SelectOriginalWaveFileButton = uibutton(app.HideTextPanel, 'push');
            app.SelectOriginalWaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOriginalWaveFileButtonPushed, true);
            app.SelectOriginalWaveFileButton.Text = 'Select Original Wave File';
            app.SelectOriginalTextFileButton = uibutton(app.HideTextPanel, 'push');
            app.SelectOriginalTextFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOriginalTextFileButtonPushed, true);
            app.SelectOriginalTextFileButton.Text = 'Select Original Text File';
            app.HideButton = uibutton(app.HideTextPanel, 'push');
            app.HideButton.ButtonPushedFcn = createCallbackFcn(app, @HideButtonPushed, true);
            app.HideButton.Text = 'Hide!';
            app.Lamp = uilamp(app.HideTextPanel);
            app.EncryptionKeyEditFieldLabel = uilabel(app.HideTextPanel);
            app.EncryptionKeyEditFieldLabel.Text = 'Encryption Key';
            app.EncryptionKeyEditField = uieditfield(app.HideTextPanel, 'text');
            app.EncryptionKeyEditField.ValueChangingFcn = createCallbackFcn(app, @EncryptionKeyEditFieldValueChanging, true);
            app.RecoverTextPanel = uipanel(app.AudioStegnographyUIFigure);
            app.RecoverTextPanel.Title = 'Recover Text';
            app.SelectEmbeddedWaveFileButton_2 = uibutton(app.RecoverTextPanel, 'push');
            app.SelectEmbeddedWaveFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectEmbeddedWaveFileButton_2Pushed, true);
            app.SelectEmbeddedWaveFileButton_2.Text = 'Select Embedded Wave File';
            app.RecoverButton = uibutton(app.RecoverTextPanel, 'push');
            app.RecoverButton.ButtonPushedFcn = createCallbackFcn(app, @RecoverButtonPushed, true);
            app.RecoverButton.Text = 'Recover!';
            app.Lamp_2 = uilamp(app.RecoverTextPanel);
            app.DecryptionKeyEditFieldLabel = uilabel(app.RecoverTextPanel);
            app.DecryptionKeyEditFieldLabel.Text = 'Decryption Key';
            app.DecryptionKeyEditField = uieditfield(app.RecoverTextPanel, 'text');
            app.DecryptionKeyEditField.ValueChangingFcn = createCallbackFcn(app, @DecryptionKeyEditFieldValueChanging, true);
            app.AudioSteganographyLabel = uilabel(app.AudioStegnographyUIFigure);
            app.AudioSteganographyLabel.Text = 'Audio Steganography';
            app.PlayOriginalButton = uibutton(app.AudioStegnographyUIFigure, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Text = 'Play Original';
            app.PlayEncodedButton = uibutton(app.AudioStegnographyUIFigure, 'push');
            app.PlayEncodedButton.ButtonPushedFcn = createCallbackFcn(app, @PlayEncodedButtonPushed, true);
            app.PlayEncodedButton.Text = 'Play Encoded';
            app.PlotOriginalButton = uibutton(app.AudioStegnographyUIFigure, 'push');
            app.PlotOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOriginalButtonPushed, true);
            app.PlotOriginalButton.Text = 'Plot Original';
            app.PlotEncodedButton = uibutton(app.AudioStegnographyUIFigure, 'push');
            app.PlotEncodedButton.ButtonPushedFcn = createCallbackFcn(app, @PlotEncodedButtonPushed, true);
            app.PlotEncodedButton.Text = 'Plot Encoded';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AudioStegnography

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudioStegnographyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioStegnographyUIFigure)
        end
    end
end